classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PlayorganButton    matlab.ui.control.Button
        PlayguitarButton   matlab.ui.control.Button
        PlayviolinButton   matlab.ui.control.Button
        WritetomidiButton  matlab.ui.control.Button
        PlaypianoButton    matlab.ui.control.Button
        AmadeusLabel       matlab.ui.control.Label
        WritetotxtButton   matlab.ui.control.Button
        ImportButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        DataFileName % Path to data file
        midiData % Data from Midi files
        txtFileName % File to write midi data
        audioFilePath = 'D:\Develop\Amadeus\Materials\output.wav';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Показать диалоговое окно выбора файла
            [filename, path] = uigetfile('*.midi;*.mid', 'Выберите MIDI-файл');

            % Проверить, был ли выбран файл
            if isequal(filename, 0)
                disp('Выбор файла отменен');
            else
                % Полный путь к выбранному файлу
                fullPath = fullfile(path, filename);
                app.DataFileName = fullPath;
                % Далее выполните необходимые действия с выбранным файлом
                % Например, загрузите данные и выполните ваши операции
                disp(['Выбран MIDI-файл: ', fullPath]);
            end
        end

        % Button pushed function: WritetotxtButton
        function WritetotxtButtonPushed(app, event)
            addpath(genpath('D:\Develop\Amadeus\Materials\matlab-midi-master\src'));
            midiFile = readmidi(app.DataFileName);
            app.midiData = midiInfo(midiFile);
            app.txtFileName = 'D:\Develop\Amadeus\Materials\output.txt';
            writematrix(app.midiData, app.txtFileName);
        end

        % Button pushed function: WritetomidiButton
        function WritetomidiButtonPushed(app, event)
            matrixData = load(app.txtFileName);
            midi_new = matrix2midi(matrixData);
            writemidi(midi_new, 'D:\Develop\Amadeus\Materials\testout.mid');
        end

        % Button pushed function: PlaypianoButton
        function PlaypianoButtonPushed(app, event)
            fluidsynthPath = 'D:\Develop\Amadeus\Materials\FluidSynth\bin\fluidsynth.exe';
            soundFontPath = 'D:\Develop\Amadeus\Materials\SoundFont\piano.sf2';
            command = sprintf('%s -a coreaudio -g 0.5 -l -F %s %s %s', fluidsynthPath, app.audioFilePath, soundFontPath, app.DataFileName);
            system(command);
            [y,Fs] = audioread(app.audioFilePath);
            sound(y, Fs);
        end

        % Button pushed function: PlayorganButton
        function PlayorganButtonPushed(app, event)
            fluidsynthPath = 'D:\Develop\Amadeus\Materials\FluidSynth\bin\fluidsynth.exe';
            soundFontPath = 'D:\Develop\Amadeus\Materials\SoundFont\organ.sf2';
            command = sprintf('%s -a coreaudio -g 0.5 -l -F %s %s %s', fluidsynthPath, app.audioFilePath, soundFontPath, app.DataFileName);
            system(command);
            [y,Fs] = audioread(app.audioFilePath);
            sound(y, Fs);
        end

        % Button pushed function: PlayguitarButton
        function PlayguitarButtonPushed(app, event)
            fluidsynthPath = 'D:\Develop\Amadeus\Materials\FluidSynth\bin\fluidsynth.exe';
            soundFontPath = 'D:\Develop\Amadeus\Materials\SoundFont\guitar.sf2';
            command = sprintf('%s -a coreaudio -g 0.5 -l -F %s %s %s', fluidsynthPath, app.audioFilePath, soundFontPath, app.DataFileName);
            system(command);
            [y,Fs] = audioread(app.audioFilePath);
            sound(y, Fs);
        end

        % Button pushed function: PlayviolinButton
        function PlayviolinButtonPushed(app, event)
            fluidsynthPath = 'D:\Develop\Amadeus\Materials\FluidSynth\bin\fluidsynth.exe';
            soundFontPath = 'D:\Develop\Amadeus\Materials\SoundFont\violin.sf2';
            command = sprintf('%s -a coreaudio -g 0.5 -l -F %s %s %s', fluidsynthPath, app.audioFilePath, soundFontPath, app.DataFileName);
            system(command);
            [y,Fs] = audioread(app.audioFilePath);
            sound(y, Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.WritetotxtButton = uibutton(app.UIFigure, 'push');
            app.WritetotxtButton.ButtonPushedFcn = createCallbackFcn(app, @WritetotxtButtonPushed, true);
            app.WritetotxtButton.Text = 'Write to txt';
            app.AmadeusLabel = uilabel(app.UIFigure);
            app.AmadeusLabel.Text = 'Amadeus';
            app.PlaypianoButton = uibutton(app.UIFigure, 'push');
            app.PlaypianoButton.ButtonPushedFcn = createCallbackFcn(app, @PlaypianoButtonPushed, true);
            app.PlaypianoButton.Text = 'Play piano';
            app.WritetomidiButton = uibutton(app.UIFigure, 'push');
            app.WritetomidiButton.ButtonPushedFcn = createCallbackFcn(app, @WritetomidiButtonPushed, true);
            app.WritetomidiButton.Text = 'Write to midi';
            app.PlayviolinButton = uibutton(app.UIFigure, 'push');
            app.PlayviolinButton.ButtonPushedFcn = createCallbackFcn(app, @PlayviolinButtonPushed, true);
            app.PlayviolinButton.Text = 'Play violin';
            app.PlayguitarButton = uibutton(app.UIFigure, 'push');
            app.PlayguitarButton.ButtonPushedFcn = createCallbackFcn(app, @PlayguitarButtonPushed, true);
            app.PlayguitarButton.Text = 'Play guitar';
            app.PlayorganButton = uibutton(app.UIFigure, 'push');
            app.PlayorganButton.ButtonPushedFcn = createCallbackFcn(app, @PlayorganButtonPushed, true);
            app.PlayorganButton.Text = 'Play organ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end